classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EstimatedrootEditField         matlab.ui.control.NumericEditField
        EstimatedrootEditFieldLabel    matlab.ui.control.Label
        StartingxEditField             matlab.ui.control.NumericEditField
        StartingxEditFieldLabel        matlab.ui.control.Label
        NumberofiterationsSlider       matlab.ui.control.Slider
        NumberofiterationsSliderLabel  matlab.ui.control.Label
        PolynomialcoefficientsEditField  matlab.ui.control.EditField
        PolynomialcoefficientsEditFieldLabel  matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        a % Description
        reps_max % Description
        x0 % Description
        yl % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            y = w2n_vector(app.a, linspace(-1,1,1000));
            plot(app.UIAxes,linspace(-1,1,1000), y);
            app.yl = yline(app.UIAxes, 0);
            v =Halley(app.a, app.x0, app.reps_max);
            plot(app.UIAxes2, 1:app.reps_max , v);
            app.EstimatedrootEditField.Value = v(length(v));

        end

        % Value changed function: PolynomialcoefficientsEditField
        function PolynomialcoefficientsEditFieldValueChanged(app, event)
            app.a = app.PolynomialcoefficientsEditField.Value;
            app.a = str2num(app.a);
        end

        % Value changed function: NumberofiterationsSlider
        function NumberofiterationsSliderValueChanged(app, event)
            app.reps_max = app.NumberofiterationsSlider.Value;
        end

        % Callback function
        function StartingxSliderValueChanged(app, event)
            app.x0 = app.StartingxSlider.Value;
        end

        % Value changed function: StartingxEditField
        function StartingxEditFieldValueChanged(app, event)
            app.x0 = app.StartingxEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'x value depending on the number of iterations ')
            xlabel(app.UIAxes2, 'iterations')
            ylabel(app.UIAxes2, 'x')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YLim = [-1.5 1.5];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'w2n value')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'w2n(x)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-1 1];
            app.UIAxes.YLimitMethod = 'padded';
            app.UIAxes.XTick = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PolynomialcoefficientsEditFieldLabel = uilabel(app.UIFigure);
            app.PolynomialcoefficientsEditFieldLabel.Text = 'Polynomial coefficients';
            app.PolynomialcoefficientsEditField = uieditfield(app.UIFigure, 'text');
            app.PolynomialcoefficientsEditField.ValueChangedFcn = createCallbackFcn(app, @PolynomialcoefficientsEditFieldValueChanged, true);
            app.NumberofiterationsSliderLabel = uilabel(app.UIFigure);
            app.NumberofiterationsSliderLabel.Text = 'Number of iterations';
            app.NumberofiterationsSlider = uislider(app.UIFigure);
            app.NumberofiterationsSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofiterationsSliderValueChanged, true);
            app.NumberofiterationsSlider.Value = 10;
            app.StartingxEditFieldLabel = uilabel(app.UIFigure);
            app.StartingxEditFieldLabel.Text = 'Starting x';
            app.StartingxEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingxEditField.Limits = [-1 1];
            app.StartingxEditField.ValueChangedFcn = createCallbackFcn(app, @StartingxEditFieldValueChanged, true);
            app.EstimatedrootEditFieldLabel = uilabel(app.UIFigure);
            app.EstimatedrootEditFieldLabel.Text = 'Estimated root';
            app.EstimatedrootEditField = uieditfield(app.UIFigure, 'numeric');
            app.EstimatedrootEditField.ValueDisplayFormat = '%1.20g';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end